classdef batch_LFP_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileInputMenu                   matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        binarydirectoryadibinMenu       matlab.ui.container.Menu
        matlabdirectorymatMenu          matlab.ui.container.Menu
        SetanalysisfolderrawPSDMenu     matlab.ui.container.Menu
        SetanalysisfolderprocessedPSDMenu  matlab.ui.container.Menu
        GetmultipleanalysisfoldersMenu  matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        GetEMGrmsMenu                   matlab.ui.container.Menu
        AnalyseEMGrmsMenu               matlab.ui.container.Menu
        SetLFPEMGfolderMenu             matlab.ui.container.Menu
        EMGLFPcorrelationMenu           matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        SettimelockedconditionsMenu     matlab.ui.container.Menu
        GetConditiontimesMenu           matlab.ui.container.Menu
        ExcludeExperimentsMenu          matlab.ui.container.Menu
        DeleteAnalysedFilesMenu         matlab.ui.container.Menu
        SummaryPlotsMenu                matlab.ui.container.Menu
        ClosePlotsMenu                  matlab.ui.container.Menu
        SpectogramMenu                  matlab.ui.container.Menu
        SubPlotMenu                     matlab.ui.container.Menu
        IndPlotMenu                     matlab.ui.container.Menu
        PowerspectumMenu                matlab.ui.container.Menu
        PSDAverPlotMenu                 matlab.ui.container.Menu
        PSDSubPlotMenu                  matlab.ui.container.Menu
        PSDIndividualPlotMenu           matlab.ui.container.Menu
        PSDparametersTimeMenu           matlab.ui.container.Menu
        AverPlotMenu                    matlab.ui.container.Menu
        AverRatioPlotMenu               matlab.ui.container.Menu
        IndPlotMenu_2                   matlab.ui.container.Menu
        PSDparametersAverageMenu        matlab.ui.container.Menu
        BarPlotMenu                     matlab.ui.container.Menu
        BoxPlotMenu                     matlab.ui.container.Menu
        DotPlotMenu                     matlab.ui.container.Menu
        PSDparametersRatioMenu          matlab.ui.container.Menu
        MultipleComparisonPlotsMenu     matlab.ui.container.Menu
        ClosePlotsMenu_2                matlab.ui.container.Menu
        ComparePSDparametersMenu        matlab.ui.container.Menu
        ComparePSDparameterratioMenu    matlab.ui.container.Menu
        MovementPlotsMenu               matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        Menu3                           matlab.ui.container.Menu
        ExportDataMenu                  matlab.ui.container.Menu
        ExcelMenu                       matlab.ui.container.Menu
        MeanPSDParametersMenu           matlab.ui.container.Menu
        PSDParameterstimeMenu           matlab.ui.container.Menu
        MatLabMenu                      matlab.ui.container.Menu
        MeanPSDparametersMenu           matlab.ui.container.Menu
        PSDparameterstimeMenu           matlab.ui.container.Menu
        MatlabstatsMenu                 matlab.ui.container.Menu
        onesamplettestMenu              matlab.ui.container.Menu
        twosamplettestMenu              matlab.ui.container.Menu
        pairedttestMenu                 matlab.ui.container.Menu
        ANOVAMenu                       matlab.ui.container.Menu
        RepeatedMeasuresMenu            matlab.ui.container.Menu
        OneWayAnovaMenu                 matlab.ui.container.Menu
        CorrelationMenu_2               matlab.ui.container.Menu
        PearsonMenu                     matlab.ui.container.Menu
        RESETButton                     matlab.ui.control.Button
        LoadDataPathEditField           matlab.ui.control.EditField
        StatusEditFieldLabel            matlab.ui.control.Label
        StatusEditField                 matlab.ui.control.EditField
        ToLabel                         matlab.ui.control.Label
        ToEditField                     matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
        FromEditFieldLabel              matlab.ui.control.Label
        FromEditField                   matlab.ui.control.NumericEditField
        RawdatafolderButton             matlab.ui.control.Button
        GetFFTLabel                     matlab.ui.control.Label
        GetFFTSwitch                    matlab.ui.control.Switch
        Lamp                            matlab.ui.control.Lamp
        PowerSpectralDensityprocessingButton  matlab.ui.control.Button
        ABORTButton                     matlab.ui.control.Button
        EnterConditionsEditFieldLabel   matlab.ui.control.Label
        EnterConditionsEditField        matlab.ui.control.EditField
    end

    
    properties (Access = private)
        x; % spectral_analysis_batch object
        emg_obj; % emg analysis object
        folder_list;
        file_type = ''; % bin vs mat file
        emg_dir = '';
        % user input app regarding PSD processing
        Dialog_App;
        
    end
    
    methods (Access = public)
        
        function update_psdproc(app, normV, linearV, noiseV, noise_bandV, outlierV,bin_sizeV)
            
            % Store inputs as spectral_analysis_batch object properties
            app.x.norm_var = normV;
            app.x.linear_var = linearV;
            app.x.noise_var = noiseV;
            app.x.noisewidth_var = noise_bandV ;
            app.x.outlier_var = outlierV;
            app.x.bin_size = bin_sizeV;
            
        end
        
    end
    
    methods (Access = private)
        
        function enable_disable_FFT_button(app,enable_var)
            
            if enable_var == 1
                
                app.GetFFTLabel.Enable = 'on';
                app.GetFFTSwitch.Enable = 'on';
                app.FromEditField.Enable = 'on';
                app.FromEditFieldLabel.Enable = 'on';
                app.ToLabel.Enable = 'on';
                app.ToEditField.Enable = 'on';
                app.HzLabel.Enable = 'on';
                app.GetFFTSwitch.Enable = 'on';
                % EMG
                app.GetEMGrmsMenu.Enable  = 'on';
                
            elseif enable_var == 0
                
                app.GetFFTLabel.Enable = 'off';
                app.GetFFTSwitch.Enable = 'off';
                app.FromEditField.Enable = 'off';
                app.FromEditFieldLabel.Enable = 'off';
                app.ToLabel.Enable = 'off';
                app.ToEditField.Enable = 'off';
                app.HzLabel.Enable = 'off';
                app.GetFFTSwitch.Enable = 'off';
                % EMG
                app.GetEMGrmsMenu.Enable  = 'off';
                
            end
        end
        
        
        function enable_disable_Menu(app,enable_var)
            
            if enable_var == 1
                
                app.SummaryPlotsMenu.Enable = 'on';
                app.MatlabstatsMenu.Enable = 'on';
                app.ExportDataMenu.Enable = 'on';
                app.ExcludeExperimentsMenu.Enable = 'on';
                app.SettimelockedconditionsMenu.Enable = 'on';
                app.GetConditiontimesMenu.Enable = 'on';
                
                app.EnterConditionsEditField.Enable = 'on';
                app.EnterConditionsEditFieldLabel.Enable = 'on';


            elseif enable_var == 0
                
                app.SummaryPlotsMenu.Enable = 'off';
                app.MatlabstatsMenu.Enable = 'off';
                app.ExportDataMenu.Enable = 'off';
                app.ExcludeExperimentsMenu.Enable = 'off';
                app.SettimelockedconditionsMenu.Enable = 'off';
                app.GetConditiontimesMenu.Enable = 'off';
                
                app.EnterConditionsEditField.Enable = 'off';
                app.EnterConditionsEditFieldLabel.Enable = 'off';
                
            end
            
        end
        
        function time_locked_separation(app)
            % get file length
            [colnames, the_list,~] = mat_table(app.x);
            f = figure();
            uitable(f, 'Data', the_list, 'ColumnName', colnames);
            
            % wait for user to close the table
            uiwait(f);
            
            % get user input
            prompt = {'Conditions (separated by ;) :',' Condition duration - mins (space separated) :'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'base;etoh','5 9'};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            if isempty(answer) == 0 % proceed only if user clicks ok
                app.x.condition_id = strsplit(answer{1},';');
                app.x.condition_time = str2num(answer{2});
                
                extract_psd_pharm(app.x);
            end
            
        end
        
    end
    

    methods (Access = private)

        % Menu selected function: binarydirectoryadibinMenu
        function binarydirectoryadibinMenuSelected(app, event)
            % create batch analysis instance
            app.x = spectral_analysis_batch;
            
            % get raw data path
            temp_path = uigetdir(app.x.desktop_path);
            if temp_path == 0
                return
            end
            app.x.lfp_data_path =  [temp_path '\'];
            
            % display new path
            app.LoadDataPathEditField.Value = app.x.lfp_data_path;
            
            % get exp_length length
            [colnames, the_list,~] = bin_table(app.x);
            f = figure();
            uitable(f, 'Data', the_list, 'ColumnName', colnames);
            % wait for user to close the table
            uiwait(f);
            
            % get data properties
            prompt = {'Channel Number','Channel structure:','Sampling rate', 'Analysed duration (hours)'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'1','BLA; PFC; EMG','4000','12'};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            % pass inputs to class properties
            app.x.channel_No = str2double(answer{1}); % channel to be analysed
            app.x.channel_struct = strsplit(answer{2},';'); % channel structure
            app.x.Tchannels = length(app.x.channel_struct); % total channel number
            app.x.Fs = str2double(answer{3});
            app.x.Nperiods = round(str2double(answer{4}));
            
            % set  bin file choice
            app.file_type = 'bin_file';
            
            % update announcement
            app.StatusEditField.Value = 'Binary (long file) directory loaded';
            
            % turn on FFT processing
            enable_disable_FFT_button(app,1)
        end

        % Menu selected function: matlabdirectorymatMenu
        function matlabdirectorymatMenuSelected(app, event)
            app.x = spectral_analysis_batch;
            
            % get raw data path
            temp_path = uigetdir(app.x.desktop_path);
            if temp_path == 0
                return
            end
            app.x.lfp_data_path =  [temp_path '\'];
            
            % display new path
            app.LoadDataPathEditField.Value = app.x.lfp_data_path;
            
            % get channel structure
            prompt = {'Channel Number:','Channel structure (separated by ;):'};
            Prompt_title = 'Input'; dims = [1 45];
            definput = {'1','BLA',};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            % pass inputs to class properties
            app.x.channel_No = str2double(answer{1});
            app.x.channel_struct = strsplit(answer{2},';');
            
            % set matlab file choice
            app.file_type = 'mat_file';
            
            % update announcement
            app.StatusEditField.Value = 'Matlab directory loaded';
            
            % turn on FFT processing
            enable_disable_FFT_button(app,1);
        end

        % Value changed function: GetFFTSwitch
        function GetFFTSwitchValueChanged(app, event)
            if strcmp(app.GetFFTSwitch.Value,'on')
                
                % get frequencies
                app.x.LowFCut = app.FromEditField.Value;
                app.x.HighFCut = app.ToEditField.Value;
                
                if strcmp(app.file_type, 'mat_file')
                    
                    % matlab interactive?
                    answer = questdlg('Separate Conditions?','User Input' ,'Yes', 'No', 'Yes');
                    
                    % Handle response
                    switch answer
                        case 'Yes'
                            % get channel structure
                            prompt = {'Low Frequency:','High Frequency:'};
                            Prompt_title = 'For observation only'; dims = [1 80];
                            definput = {'5','80'};
                            answer2 = inputmod(prompt,Prompt_title,dims,definput);
                            
                            % extract power matrix interactive
                            extract_pmatrix_mat_user(app.x,str2double(answer2{1}),str2double(answer2{2}));
                            
                        case 'No'
                            extract_pmatrix_mat(app.x);
                    end
                    
                    % matlab interactive?
                    answer = questdlg('Time-Locked Separation','User Input' ,'Yes', 'No', 'Yes');
                    
                    if strcmp(answer,'Yes')
                        % separate based on time
                        time_locked_separation(app);
                    elseif strcmp(answer,'No')
                        % copy files to raw psd path for psd preprocessing
                        app.x.copy_user_files(app.x.raw_psd_user,app.x.raw_psd_path,'mat');
                    end
                    
                    
                elseif strcmp(app.file_type, 'bin_file')
                    % long binary file analysis
                    extract_pmatrix_bin(app.x);
                    
                end
                
                
                % Turn on Condition Separation
                app.SettimelockedconditionsMenu.Enable = 'on';
                
                % turn on PSD processing
                app.PowerSpectralDensityprocessingButton.Enable = 'on';
                
                % change lamp color to green
                app.Lamp.Color = [0 1 0];
                
                % update announcement
                app.StatusEditField.Value = 'FFT analysis done.';
                
                % turn off FFT processing
                enable_disable_FFT_button(app,0);
            end
            
        end

        % Button pushed function: PowerSpectralDensityprocessingButton
        function PowerSpectralDensityprocessingButtonPushed(app, event)
            
            % Open the options dialog and pass inputs
            app.Dialog_App = psd_proc_uinput(app,  app.x.norm_var,  app.x.linear_var, app.x.noise_var, app.x.noisewidth_var, app.x.outlier_var, app.x.bin_size);
            
            % wait for user input
            waitfor(app.Dialog_App);
            
            if app.x.noisewidth_var ~= -1
                
                % get divisible bin
                if app.x.bin_size ~= -1
                    app.x.bin_size = round(app.x.bin_size/app.x.dur) * app.x.dur;
                end
                
                % Run psd processing and save processed psds
                psd_processing(app.x);
                
                % enable plot and statistics
                enable_disable_Menu(app,1);
                
                % get conditions
                app.EnterConditionsEditField.Value= strjoin(app.x.condition_id,';');
                
                % update announcement
                app.StatusEditField.Value = 'PSD processing completed.';
                
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete both apps
            delete(app.Dialog_App);
            delete(app);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            enable_disable_FFT_button(app,0);
            app.GetFFTSwitch.Value = 'off';
            app.DeleteAnalysedFilesMenu.Enable = 'off';
            app.MultipleComparisonPlotsMenu.Enable = 'off';
            app.EMGLFPcorrelationMenu.Enable = 'off';
            enable_disable_Menu(app,0);
            app.Lamp.Color = [1 0 0];
            app.PowerSpectralDensityprocessingButton.Enable = 'off';
            app.SettimelockedconditionsMenu.Enable = 'off';
            app.StatusEditField.Value = 'Load data or set analysed folder to proceed...';
            app.LoadDataPathEditField.Value = 'C:\';
            clear app.x
        end

        % Menu selected function: SetanalysisfolderrawPSDMenu
        function SetanalysisfolderrawPSDMenuSelected(app, event)
            % create batch analysis instance
            app.x = spectral_analysis_batch;
            
            % reload saved object
            app.x = reload_object(app.x);
            
            % check if user loaded object
            if app.x == 0
                return
            end
            
            % set path
            app.LoadDataPathEditField.Value = app.x.raw_psd_path;
            
            % activate psd processing
            app.PowerSpectralDensityprocessingButton.Enable = 'on';
            
            % activate condition separation
            app.SettimelockedconditionsMenu.Enable = 'on';
            
            % delete files menu on
            app.DeleteAnalysedFilesMenu.Enable = 'on';
            
        end

        % Menu selected function: SetanalysisfolderprocessedPSDMenu
        function SetanalysisfolderprocessedPSDMenuSelected(app, event)
            % create batch analysis instance
            app.x = spectral_analysis_batch;
            
            % reload saved object
            app.x = reload_object(app.x);
            
            % check if user loaded object
            if app.x == 0
                return
            end
            
            % activate plot stats menu
            enable_disable_Menu(app,1);
            % delete files menu on
            app.DeleteAnalysedFilesMenu.Enable = 'on';
            % Get conditions
            app.EnterConditionsEditField.Value = strjoin(app.x.condition_id,';');
        end

        % Menu selected function: PSDAverPlotMenu
        function PSDAverPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            prompt = {'Low Frequency:','High Frequency:'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'2','80'};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            % plot spectogram
            plot_meanPSD(app.x,str2double(answer{1}),str2double(answer{2}))
        end

        % Menu selected function: PSDSubPlotMenu
        function PSDSubPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            prompt = {'Low Frequency:','High Frequency:'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'2','80'};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            % plot spectogram
            plot_subPSD(app.x,str2double(answer{1}),str2double(answer{2}));
        end

        % Menu selected function: ExcludeExperimentsMenu
        function ExcludeExperimentsMenuSelected(app, event)
            % exlude processed PSDs
            exp_exclude(app.x);
        end

        % Menu selected function: DotPlotMenu
        function DotPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{5}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % get parameters over time
            aver_psd_prm(app.x,a,1); % 1 for dot plot
        end

        % Menu selected function: BarPlotMenu
        function BarPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{5}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % get parameters over time
            aver_psd_prm(app.x,a,2); % 2 for bar plot
        end

        % Menu selected function: BoxPlotMenu
        function BoxPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{5}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % get parameters over time
            aver_psd_prm(app.x,a,3); % 3 for box plot
        end

        % Menu selected function: PSDIndividualPlotMenu
        function PSDIndividualPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            prompt = {'Low Frequency:','High Frequency:'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'2','80'};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            % plot spectogram
            plot_indPSD(app.x,str2double(answer{1}),str2double(answer{2}))
        end

        % Menu selected function: DeleteAnalysedFilesMenu
        function DeleteAnalysedFilesMenuSelected(app, event)
            % delete analysed files
            del_files(app.x);
            
            % update announcement
            app.StatusEditField.Value = 'Files Deleted';
        end

        % Menu selected function: ClosePlotsMenu, ClosePlotsMenu_2
        function ClosePlotsMenuSelected(app, event)
            close all;
        end

        % Menu selected function: SubPlotMenu
        function SubPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get channel structure
            prompt = {'Low Frequency:','High Frequency:'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'2','80'};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            % plot spectogram
            ret_str = spectrogram_subplot(app.x,str2double(answer{1}),str2double(answer{2}));
            
            if isempty(ret_str) == 0
                uialert(app.UIFigure,ret_str,'Error.')
            end
        end

        % Menu selected function: IndPlotMenu
        function IndPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get channel structure
            prompt = {'Low Frequency:','High Frequency:'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'2','80'};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            % plot spectogram
            ret_str = spectrogram_indplot(app.x,str2double(answer{1}),str2double(answer{2}));
            
            if isempty(ret_str) == 0
                uialert(app.UIFigure,ret_str,'Error.')
            end
        end

        % Menu selected function: AverPlotMenu
        function AverPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{5}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % get parameters over time
            psd_prm_time(app.x,a);
        end

        % Menu selected function: IndPlotMenu_2
        function IndPlotMenu_2Selected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{5}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % get parameters over time
            psd_prm_time_ind(app.x,a);
        end

        % Menu selected function: SettimelockedconditionsMenu
        function SettimelockedconditionsMenuSelected(app, event)
            % call the time locked separation method
            time_locked_separation(app)
        end

        % Menu selected function: GetConditiontimesMenu
        function GetConditiontimesMenuSelected(app, event)
            % reload object
            s=load(fullfile(app.x.save_path,'psd_object'));
            app.x = copy(s.psd_object);
            
            % get power matrix blocks
            get_cond_times(app.x,app.x.proc_psd_path)
            
            % save psd_object
            psd_object = saveobj(app.x); %#ok
            save (fullfile(app.x.save_path,'psd_object.mat'),'psd_object')
        end

        % Menu selected function: MeanPSDParametersMenu
        function MeanPSDParametersMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get channel structure
            prompt = {'Low Frequency:','High Frequency:'};
            Prompt_title = 'Input'; dims = [1 35];
            definput = {'2','80'};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            % plot spectogram
            excel_meanprms(app.x,str2double(answer{1}),str2double(answer{2}));
            
        end

        % Menu selected function: AverRatioPlotMenu
        function AverRatioPlotMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt_ratio();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.band1 = answer{1};
            a.band2 = answer{2};
            a.band_width = answer{3};
            a.par_var = answer{4};
            a.norms_v = answer{5};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{6}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % get parameters over time
            prm_ratio_time(app.x,a);
        end

        % Menu selected function: PSDparametersRatioMenu
        function PSDparametersRatioMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt_plot();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.band1 = answer{1};
            a.band2 = answer{2};
            a.band_width = answer{3};
            a.par_var = answer{4};
            a.norms_v = answer{5};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{6}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % get parameters over time
            aver_psd_prm_ratio(app.x,a,answer{7}); % 2 for bar plot
        end

        % Menu selected function: ComparePSDparametersMenu
        function ComparePSDparametersMenuSelected(app, event)
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{5}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % plot psd parameters over time
            cmp_psd_prm_time(app.x,a,app.folder_list,false);
            
            % plot psd parameter average
            cmp_psd_prm_aver(app.x,a,app.folder_list,false);
        end

        % Menu selected function: ComparePSDparameterratioMenu
        function ComparePSDparameterratioMenuSelected(app, event)
            % get user input
            [answer, canceled]  = app.x.input_prompt_plot();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.band1 = answer{1};
            a.band2 = answer{2};
            a.band_width = answer{3};
            a.par_var = answer{4};
            a.norms_v = answer{5};
            a. mean_v = false;
            a.ind_v = false;
            
            switch answer{6}
                case 1
                    a. mean_v = true;
                case 2
                    a.ind_v = true;
                case 3
                    a. mean_v = true;
                    a.ind_v = true;
            end
            
            % plot psd parameters over time
            cmp_psd_prm_time(app.x,a,app.folder_list,true);
            
            % plot psd parameter average
            cmp_psd_prm_aver(app.x,a,app.folder_list,true);
        end

        % Menu selected function: AnalyseEMGrmsMenu
        function AnalyseEMGrmsMenuSelected(app, event)
            app.x = spectral_analysis_batch;
            
            % get raw data path
            temp_path = uigetdir(app.x.desktop_path,'load EMG directory');
            
            if temp_path == 0
                return
            end
            app.x.lfp_data_path =  [temp_path '\'];
            
            % display new path
            app.LoadDataPathEditField.Value = app.x.lfp_data_path;
            
            % get channel structure
            prompt = {'Channel Number:','Channel structure (separated by ;):'};
            Prompt_title = 'Input'; dims = [1 45];
            definput = {'1','EMG',};
            answer = inputmod(prompt,Prompt_title,dims,definput);
            
            % pass inputs to class properties
            app.x.channel_No = str2double(answer{1});
            app.x.channel_struct = strsplit(answer{2},';');
            
            % set matlab file choice
            % app.file_type = 'mat_file';
            
            % get lfp object path
            k = strfind(temp_path,'\');
            lfp_obj_path = uigetdir(temp_path(1:k(end)),'load lfp file object directory');
            
            if lfp_obj_path == 0
                return
            end
            
            % get rms values
            Get_EMG(app.x,lfp_obj_path);
            
            % update announcement
            app.StatusEditField.Value = 'EMG analysis completed';
        end

        % Menu selected function: GetmultipleanalysisfoldersMenu
        function GetmultipleanalysisfoldersMenuSelected(app, event)
            app.x = spectral_analysis_batch;
            app.folder_list = app.x.obtain_dir_list(app.x.desktop_path);
            if isempty(app.folder_list)
                return
            end
            app.MultipleComparisonPlotsMenu.Enable = 'on';
            app.StatusEditField.Value = 'Multiple analysis folders loaded';
            %             % get conditions
            %             app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
        end

        % Menu selected function: EMGLFPcorrelationMenu
        function EMGLFPcorrelationMenuSelected(app, event)
            % get conditions
            app.x.condition_id = strsplit(app.EnterConditionsEditField.Value,';');
            
            % get user input
            [answer, canceled]  = app.x.input_prompt();
            if canceled ==1
                return
            end
            
            % put parameters in a structure
            a.Flow = answer{1};
            a.Fhigh = answer{2};
            a.par_var = answer{3};
            a.norms_v = answer{4};
            a. mean_v = false;
            a.ind_v = false;
            
            % plot the correlation of lfp vs EMG
            ret_str = corr_psd_prm_emg(app.x,a,app.emg_dir);
            
            if isempty(ret_str) == 0
                uialert(app.UIFigure,ret_str,'Error.');
            end
        end

        % Menu selected function: SetLFPEMGfolderMenu
        function SetLFPEMGfolderMenuSelected(app, event)
            % create batch analysis instance
            app.x = spectral_analysis_batch;
            
            % reload saved object
            app.x = reload_object(app.x);
            
            % check if user loaded object
            if app.x == 0
                return
            end
            
            % get parameters over time
            % get lfp object path
            k = strfind(app.x.save_path,'\');
            app.emg_dir = uigetdir(app.x.save_path(1:k(end)),'get EMG directory');
            app.emg_dir = fullfile(app.emg_dir, 'rms_waves');
            if app.x == 0
                return
            end
            
            
            % activate plot stats menu
            enable_disable_Menu(app,1);
            % delete files menu on
            app.DeleteAnalysedFilesMenu.Enable = 'on';
            % Get conditions
            app.EnterConditionsEditField.Value = strjoin(app.x.condition_id,';');
            % enable EMG-LFP analysis
            app.EMGLFPcorrelationMenu.Enable = 'on';
        end

        % Menu selected function: GetEMGrmsMenu
        function GetEMGrmsMenu_Selected(app, event)
            app.emg_obj = emg_analysis();
            
            % pass variables to emg instance
            app.emg_obj.channel_No = app.x.channel_No; % channel number
            app.emg_obj.channel_struct =  app.x.channel_struct;  % channel structure
            app.emg_obj.Tchannels = app.x.Tchannels; % total channel number
            app.emg_obj.Fs = app.x.Fs; % sampling rate
            app.emg_obj.lfp_data_path = app.x.lfp_data_path; % get path for analysis
            
            % get lfp object path
            k = strfind(app.emg_obj.lfp_data_path,'\');
            lfp_obj_path = uigetdir(app.emg_obj.lfp_data_path(1:k(end)),'load lfp file object directory (analysis folder)');
            
            if lfp_obj_path == 0
                return
            end
            
            % get rms values
            if strcmp(app.file_type, 'bin_file')
                Get_EMG_bin(app.emg_obj,lfp_obj_path);
            end
            
            % update announcement
            app.StatusEditField.Value = 'EMG analysis completed';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileInputMenu = uimenu(app.UIFigure);
            app.FileInputMenu.Text = 'File Input';
            app.LoadMenu = uimenu(app.FileInputMenu);
            app.LoadMenu.Text = 'Load';
            app.binarydirectoryadibinMenu = uimenu(app.LoadMenu);
            app.binarydirectoryadibinMenu.MenuSelectedFcn = createCallbackFcn(app, @binarydirectoryadibinMenuSelected, true);
            app.binarydirectoryadibinMenu.Text = 'binary directory (.adibin)';
            app.matlabdirectorymatMenu = uimenu(app.LoadMenu);
            app.matlabdirectorymatMenu.MenuSelectedFcn = createCallbackFcn(app, @matlabdirectorymatMenuSelected, true);
            app.matlabdirectorymatMenu.Text = 'matlab directory (.mat)';
            app.SetanalysisfolderrawPSDMenu = uimenu(app.FileInputMenu);
            app.SetanalysisfolderrawPSDMenu.MenuSelectedFcn = createCallbackFcn(app, @SetanalysisfolderrawPSDMenuSelected, true);
            app.SetanalysisfolderrawPSDMenu.Text = 'Set analysis folder (raw PSD)';
            app.SetanalysisfolderprocessedPSDMenu = uimenu(app.FileInputMenu);
            app.SetanalysisfolderprocessedPSDMenu.MenuSelectedFcn = createCallbackFcn(app, @SetanalysisfolderprocessedPSDMenuSelected, true);
            app.SetanalysisfolderprocessedPSDMenu.Text = 'Set analysis folder (processed PSD)';
            app.GetmultipleanalysisfoldersMenu = uimenu(app.FileInputMenu);
            app.GetmultipleanalysisfoldersMenu.MenuSelectedFcn = createCallbackFcn(app, @GetmultipleanalysisfoldersMenuSelected, true);
            app.GetmultipleanalysisfoldersMenu.Text = 'Get multiple analysis folders';
            app.Menu = uimenu(app.FileInputMenu);
            app.Menu.Text = '-----';
            app.GetEMGrmsMenu = uimenu(app.FileInputMenu);
            app.GetEMGrmsMenu.MenuSelectedFcn = createCallbackFcn(app, @GetEMGrmsMenu_Selected, true);
            app.GetEMGrmsMenu.Enable = 'off';
            app.GetEMGrmsMenu.Text = 'Get EMG (r.m.s)';
            app.AnalyseEMGrmsMenu = uimenu(app.FileInputMenu);
            app.AnalyseEMGrmsMenu.MenuSelectedFcn = createCallbackFcn(app, @AnalyseEMGrmsMenuSelected, true);
            app.AnalyseEMGrmsMenu.Text = 'Analyse EMG (r.m.s.)';
            app.SetLFPEMGfolderMenu = uimenu(app.FileInputMenu);
            app.SetLFPEMGfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @SetLFPEMGfolderMenuSelected, true);
            app.SetLFPEMGfolderMenu.Text = 'Set LFP-EMG folder';
            app.EMGLFPcorrelationMenu = uimenu(app.FileInputMenu);
            app.EMGLFPcorrelationMenu.MenuSelectedFcn = createCallbackFcn(app, @EMGLFPcorrelationMenuSelected, true);
            app.EMGLFPcorrelationMenu.Enable = 'off';
            app.EMGLFPcorrelationMenu.Text = 'EMG-LFP correlation';
            app.Menu_2 = uimenu(app.FileInputMenu);
            app.Menu_2.Text = '-----';
            app.SettimelockedconditionsMenu = uimenu(app.FileInputMenu);
            app.SettimelockedconditionsMenu.MenuSelectedFcn = createCallbackFcn(app, @SettimelockedconditionsMenuSelected, true);
            app.SettimelockedconditionsMenu.Enable = 'off';
            app.SettimelockedconditionsMenu.Text = 'Set time-locked conditions';
            app.GetConditiontimesMenu = uimenu(app.FileInputMenu);
            app.GetConditiontimesMenu.MenuSelectedFcn = createCallbackFcn(app, @GetConditiontimesMenuSelected, true);
            app.GetConditiontimesMenu.Enable = 'off';
            app.GetConditiontimesMenu.Text = 'Get Condition times';
            app.ExcludeExperimentsMenu = uimenu(app.FileInputMenu);
            app.ExcludeExperimentsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcludeExperimentsMenuSelected, true);
            app.ExcludeExperimentsMenu.Enable = 'off';
            app.ExcludeExperimentsMenu.Text = 'Exclude Experiments';
            app.DeleteAnalysedFilesMenu = uimenu(app.FileInputMenu);
            app.DeleteAnalysedFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteAnalysedFilesMenuSelected, true);
            app.DeleteAnalysedFilesMenu.Enable = 'off';
            app.DeleteAnalysedFilesMenu.Text = 'Delete Analysed Files';
            app.SummaryPlotsMenu = uimenu(app.UIFigure);
            app.SummaryPlotsMenu.Enable = 'off';
            app.SummaryPlotsMenu.Text = 'Summary Plots';
            app.ClosePlotsMenu = uimenu(app.SummaryPlotsMenu);
            app.ClosePlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @ClosePlotsMenuSelected, true);
            app.ClosePlotsMenu.Text = 'Close Plots';
            app.SpectogramMenu = uimenu(app.SummaryPlotsMenu);
            app.SpectogramMenu.Text = 'Spectogram';
            app.SubPlotMenu = uimenu(app.SpectogramMenu);
            app.SubPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @SubPlotMenuSelected, true);
            app.SubPlotMenu.Text = 'SubPlot';
            app.IndPlotMenu = uimenu(app.SpectogramMenu);
            app.IndPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @IndPlotMenuSelected, true);
            app.IndPlotMenu.Text = 'IndPlot';
            app.PowerspectumMenu = uimenu(app.SummaryPlotsMenu);
            app.PowerspectumMenu.Text = 'Power spectum';
            app.PSDAverPlotMenu = uimenu(app.PowerspectumMenu);
            app.PSDAverPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PSDAverPlotMenuSelected, true);
            app.PSDAverPlotMenu.Text = 'PSD Aver Plot';
            app.PSDSubPlotMenu = uimenu(app.PowerspectumMenu);
            app.PSDSubPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PSDSubPlotMenuSelected, true);
            app.PSDSubPlotMenu.Text = 'PSD Sub Plot';
            app.PSDIndividualPlotMenu = uimenu(app.PowerspectumMenu);
            app.PSDIndividualPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PSDIndividualPlotMenuSelected, true);
            app.PSDIndividualPlotMenu.Text = 'PSD Individual Plot';
            app.PSDparametersTimeMenu = uimenu(app.SummaryPlotsMenu);
            app.PSDparametersTimeMenu.Text = 'PSD parameters (Time)';
            app.AverPlotMenu = uimenu(app.PSDparametersTimeMenu);
            app.AverPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @AverPlotMenuSelected, true);
            app.AverPlotMenu.Text = 'Aver Plot';
            app.AverRatioPlotMenu = uimenu(app.PSDparametersTimeMenu);
            app.AverRatioPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @AverRatioPlotMenuSelected, true);
            app.AverRatioPlotMenu.Text = 'Aver Ratio Plot';
            app.IndPlotMenu_2 = uimenu(app.PSDparametersTimeMenu);
            app.IndPlotMenu_2.MenuSelectedFcn = createCallbackFcn(app, @IndPlotMenu_2Selected, true);
            app.IndPlotMenu_2.Text = 'Ind Plot';
            app.PSDparametersAverageMenu = uimenu(app.SummaryPlotsMenu);
            app.PSDparametersAverageMenu.Text = 'PSD parameters (Average)';
            app.BarPlotMenu = uimenu(app.PSDparametersAverageMenu);
            app.BarPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @BarPlotMenuSelected, true);
            app.BarPlotMenu.Text = 'Bar Plot';
            app.BoxPlotMenu = uimenu(app.PSDparametersAverageMenu);
            app.BoxPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @BoxPlotMenuSelected, true);
            app.BoxPlotMenu.Text = 'Box Plot';
            app.DotPlotMenu = uimenu(app.PSDparametersAverageMenu);
            app.DotPlotMenu.MenuSelectedFcn = createCallbackFcn(app, @DotPlotMenuSelected, true);
            app.DotPlotMenu.Text = 'Dot Plot';
            app.PSDparametersRatioMenu = uimenu(app.SummaryPlotsMenu);
            app.PSDparametersRatioMenu.MenuSelectedFcn = createCallbackFcn(app, @PSDparametersRatioMenuSelected, true);
            app.PSDparametersRatioMenu.Text = 'PSD parameters (Ratio)';
            app.MultipleComparisonPlotsMenu = uimenu(app.UIFigure);
            app.MultipleComparisonPlotsMenu.Enable = 'off';
            app.MultipleComparisonPlotsMenu.Text = 'Multiple Comparison Plots';
            app.ClosePlotsMenu_2 = uimenu(app.MultipleComparisonPlotsMenu);
            app.ClosePlotsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ClosePlotsMenuSelected, true);
            app.ClosePlotsMenu_2.Text = 'Close Plots';
            app.ComparePSDparametersMenu = uimenu(app.MultipleComparisonPlotsMenu);
            app.ComparePSDparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @ComparePSDparametersMenuSelected, true);
            app.ComparePSDparametersMenu.Text = 'Compare PSD parameters';
            app.ComparePSDparameterratioMenu = uimenu(app.MultipleComparisonPlotsMenu);
            app.ComparePSDparameterratioMenu.MenuSelectedFcn = createCallbackFcn(app, @ComparePSDparameterratioMenuSelected, true);
            app.ComparePSDparameterratioMenu.Text = 'Compare PSD parameter ratio';
            app.MovementPlotsMenu = uimenu(app.UIFigure);
            app.MovementPlotsMenu.Enable = 'off';
            app.MovementPlotsMenu.Text = 'Movement Plots';
            app.Menu2 = uimenu(app.MovementPlotsMenu);
            app.Menu2.Text = 'Menu2';
            app.Menu3 = uimenu(app.MovementPlotsMenu);
            app.Menu3.Text = 'Menu3';
            app.ExportDataMenu = uimenu(app.UIFigure);
            app.ExportDataMenu.Enable = 'off';
            app.ExportDataMenu.Text = 'Export Data';
            app.ExcelMenu = uimenu(app.ExportDataMenu);
            app.ExcelMenu.Text = 'Excel';
            app.MeanPSDParametersMenu = uimenu(app.ExcelMenu);
            app.MeanPSDParametersMenu.MenuSelectedFcn = createCallbackFcn(app, @MeanPSDParametersMenuSelected, true);
            app.MeanPSDParametersMenu.Text = 'Mean PSD Parameters';
            app.PSDParameterstimeMenu = uimenu(app.ExcelMenu);
            app.PSDParameterstimeMenu.Text = 'PSD Parameters - time';
            app.MatLabMenu = uimenu(app.ExportDataMenu);
            app.MatLabMenu.Text = 'MatLab';
            app.MeanPSDparametersMenu = uimenu(app.MatLabMenu);
            app.MeanPSDparametersMenu.Text = 'Mean PSD parameters';
            app.PSDparameterstimeMenu = uimenu(app.MatLabMenu);
            app.PSDparameterstimeMenu.Text = 'PSD parameters - time';
            app.MatlabstatsMenu = uimenu(app.UIFigure);
            app.MatlabstatsMenu.Enable = 'off';
            app.MatlabstatsMenu.Text = 'Matlab stats';
            app.onesamplettestMenu = uimenu(app.MatlabstatsMenu);
            app.onesamplettestMenu.Text = 'one-sample t-test';
            app.twosamplettestMenu = uimenu(app.MatlabstatsMenu);
            app.twosamplettestMenu.Text = 'two-sample t-test';
            app.pairedttestMenu = uimenu(app.MatlabstatsMenu);
            app.pairedttestMenu.Text = 'paired t-test';
            app.ANOVAMenu = uimenu(app.MatlabstatsMenu);
            app.ANOVAMenu.Text = 'ANOVA';
            app.RepeatedMeasuresMenu = uimenu(app.ANOVAMenu);
            app.RepeatedMeasuresMenu.Text = 'Repeated Measures';
            app.OneWayAnovaMenu = uimenu(app.ANOVAMenu);
            app.OneWayAnovaMenu.Text = 'One Way Anova';
            app.CorrelationMenu_2 = uimenu(app.MatlabstatsMenu);
            app.CorrelationMenu_2.Text = 'Correlation';
            app.PearsonMenu = uimenu(app.CorrelationMenu_2);
            app.PearsonMenu.Text = 'Pearson';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.LoadDataPathEditField = uieditfield(app.UIFigure, 'text');
            app.LoadDataPathEditField.Editable = 'off';
            app.LoadDataPathEditField.Value = 'C:\';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.StatusEditField.Editable = 'off';
            app.StatusEditField.Value = 'Load data or set analysed folder to proceed...';
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Enable = 'off';
            app.ToLabel.Text = 'To:';
            app.ToEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToEditField.Limits = [0 1000];
            app.ToEditField.Enable = 'off';
            app.ToEditField.Value = 200;
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Enable = 'off';
            app.HzLabel.Text = 'Hz';
            app.FromEditFieldLabel = uilabel(app.UIFigure);
            app.FromEditFieldLabel.Enable = 'off';
            app.FromEditFieldLabel.Text = 'From:';
            app.FromEditField = uieditfield(app.UIFigure, 'numeric');
            app.FromEditField.Limits = [0 1000];
            app.FromEditField.Enable = 'off';
            app.FromEditField.Value = 0.4;
            app.RawdatafolderButton = uibutton(app.UIFigure, 'push');
            app.RawdatafolderButton.Enable = 'off';
            app.RawdatafolderButton.Text = 'Raw data folder';
            app.GetFFTLabel = uilabel(app.UIFigure);
            app.GetFFTLabel.Enable = 'off';
            app.GetFFTLabel.Text = 'Get FFT';
            app.GetFFTSwitch = uiswitch(app.UIFigure, 'slider');
            app.GetFFTSwitch.Items = {'off', 'on'};
            app.GetFFTSwitch.ValueChangedFcn = createCallbackFcn(app, @GetFFTSwitchValueChanged, true);
            app.GetFFTSwitch.Enable = 'off';
            app.GetFFTSwitch.Value = 'off';
            app.Lamp = uilamp(app.UIFigure);
            app.PowerSpectralDensityprocessingButton = uibutton(app.UIFigure, 'push');
            app.PowerSpectralDensityprocessingButton.ButtonPushedFcn = createCallbackFcn(app, @PowerSpectralDensityprocessingButtonPushed, true);
            app.PowerSpectralDensityprocessingButton.Enable = 'off';
            app.PowerSpectralDensityprocessingButton.Text = 'Power Spectral Density processing...';
            app.ABORTButton = uibutton(app.UIFigure, 'push');
            app.ABORTButton.Text = 'ABORT';
            app.EnterConditionsEditFieldLabel = uilabel(app.UIFigure);
            app.EnterConditionsEditFieldLabel.Enable = 'off';
            app.EnterConditionsEditFieldLabel.Text = 'Enter Conditions';
            app.EnterConditionsEditField = uieditfield(app.UIFigure, 'text');
            app.EnterConditionsEditField.Enable = 'off';
            app.EnterConditionsEditField.Value = 'pre;post';
        end
    end

    methods (Access = public)

        % Construct app
        function app = batch_LFP_analysis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end